classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VOLUMELabel               matlab.ui.control.Label
        volumeKnob                matlab.ui.control.Knob
        playButton                matlab.ui.control.Button
        pauseButton               matlab.ui.control.Button
        stopButton                matlab.ui.control.Button
        resumeButton              matlab.ui.control.Button
        SELECTAUDIODropDownLabel  matlab.ui.control.Label
        SELECTAUDIODropDown       matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            global audioFile;
            global selected;
            if (selected == false)
                play('All I Want for Christmas.mp3')
            end
            if (selected == true)
                play(audioFile);
            end
                
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            global audioFile;
            pause(audioFile);
        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)
            global audioFile;
            resume(audioFile);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global audioFile;
            stop(audioFile);
        end

        % Value changed function: SELECTAUDIODropDown
        function SELECTAUDIODropDownValueChanged(app, event)
            global audioFile; %variable stores the audioFile that will be played
            global selected;%variable stores if an audioFile was chosen - bc if no audiofile was chosen from the drop down, audioFile would have no value
            selected = true;%we set this to true bc the playButtonPushed function uses this as a conditional for which song to play
            file = app.SELECTAUDIODropDown.Value;
            [y, Fs] = audioread(file);
            audioFile = audioplayer(y,Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VOLUMELabel = uilabel(app.UIFigure);
            app.VOLUMELabel.Text = 'VOLUME';
            app.volumeKnob = uiknob(app.UIFigure, 'continuous');
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'PLAY';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'PAUSE';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'STOP';
            app.resumeButton = uibutton(app.UIFigure, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Text = 'RESUME';
            app.SELECTAUDIODropDownLabel = uilabel(app.UIFigure);
            app.SELECTAUDIODropDownLabel.Text = 'SELECT AUDIO';
            app.SELECTAUDIODropDown = uidropdown(app.UIFigure);
            app.SELECTAUDIODropDown.Items = {'All I Want for Christmas.mp3', 'Fight Song.mp3', 'Motivational Speech.mp3', 'Police Siren.mp3'};
            app.SELECTAUDIODropDown.ValueChangedFcn = createCallbackFcn(app, @SELECTAUDIODropDownValueChanged, true);
            app.SELECTAUDIODropDown.Value = 'All I Want for Christmas.mp3';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domain')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end